classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RXenyEditField          matlab.ui.control.NumericEditField
        RXenyEditFieldLabel     matlab.ui.control.Label
        RXenxEditField          matlab.ui.control.NumericEditField
        RXenxEditFieldLabel     matlab.ui.control.Label
        AffichelesrayonsButton  matlab.ui.control.Button
        AfficherendBmButton     matlab.ui.control.Button
        AfficherendbitButton_2  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        RX1 = [45;-25];

        murs = {[[0;-45],[75;-45],[5;0.014]],[[75;-45],[75;-70],[5;0.014]],...
    [[75;-70],[100;-70],[5;0.014]],[[100;-70],[100;0],[4.6;0.02]],...
    [[100;0],[0;0],[4.6;0.02]],[[0;0],[0;-45],[4.6;0.02]],...
    [[15;0],[15;-4],[2.25;0.04]],[[15;-5],[15;-13],[2.25;0.04]],...
    [[15;-14],[15;-22],[2.25;0.04]],[[15;-23],[15;-31],[2.25;0.04]],...
    [[15;-32],[15;-40],[2.25;0.04]],[[15;-41],[15;-45],[2.25;0.04]],...
    [[0;-9],[15;-9],[2.25;0.04]],[[0;-18],[15;-18],[2.25;0.04]],...
    [[0;-27],[15;-27],[2.25;0.04]],[[0;-36],[15;-36],[2.25;0.04]],...
    [[35;-20],[40;-15],[2.25;0.04]],[[50;-15],[55;-20],[2.25;0.04]],...
    [[50;-35],[55;-30],[2.25;0.04]],[[35;-30],[40;-35],[2.25;0.04]],...
    [[85;0],[85;-12.8398],[4.6;0.02]],[[85;-14.8398],[85;-27.6796],[4.6;0.02]],...
    [[75;-45],[85;-27.6796],[4.6;0.02]],[[85;-27.6796],[91.5;-27.6796],[4.6;0.02]],...
    [[93.5;-27.6796],[100;-27.6796],[4.6;0.02]]}; 
        
    end
    
   methods (Access = private)
        
       function [debit,puissances_dbm,X,Xm,Y,Ym]= main(app)

           
            %% constantes
f = (26*10^9); % 5G 26GHz
omega = (2*pi*f);
epsilon0 = (8.85*10^-12);
mu0 = (4*pi*10^-7);
c = (3*10^8);
Z0 = sqrt(mu0/epsilon0);
beta = (omega/c);
lambda = (c/f);
Ra = 73;

%% Emetteurs
% TX1
puissanceTX1 = 20; %en dbm
gainTX1 = 1; 
PtxGtxTX1 = gainTX1*10^((puissanceTX1-30)/10);
facteurTX1 = 60*lambda^2*PtxGtxTX1/(8*pi^2*Ra);

% TX2
% puissanceTX2 = 35; %en dbm
% gainTX2 = 1; 
% % PtxGtxTX2 = gainTX2*10^((puissanceTX2-30)/10);
% % facteurTX2 = 60*lambda^2*PtxGtxTX2/(8*pi^2*Ra);

% TX3
puissanceTX3 = 35; %en dbm
gain_max = 21.5836; %en db
delta = 0.75; %en radians
phi_3db = (30*pi/180); %en radians
Ptx3 = 10^((puissanceTX3-30)/10);
facteurTX3 = 60*lambda^2*Ptx3/(8*pi^2*Ra);

%% Configuration de la piece
 

nmurs = numel(app.murs); %nombre de murs
nrefl = 1; %nombre de reflexions
TX3 = [-10;0.5]; %fixé
n = 2; %nombre de pas par mètre
X = 100; %x max
Y = 0.5; %y max
Xm = -10; %x min
Ym = -70; %y min

tic %mesure temps debut

nrayons = nombre_rayons(app,nmurs, nrefl); %calcul nombre rayons max
puissances_dbm = zeros(n*(Y-Ym)+1, n*(X-Xm)+1); %matrice des puissances
debit = zeros(n*(Y-Ym)+1, n*(X-Xm)+1); %matrice des débits
TXs = zeros(2,3);


%% TX3
TXs(:,1) = TX3;
k = 1;
[puissances_dbm]  = calcul_puissances(app,app.murs, nmurs, nrayons, nrefl, TX3, n, Ym, Y, Xm, X, Z0, ...
    beta, omega, epsilon0, mu0, puissances_dbm, 1, facteurTX3, gain_max, delta, phi_3db, TXs, k);

%% TX1
TX1_1 = [45;-25];
TXs(:,2) = TX1_1;
k = 2;
[puissances_dbm]  = calcul_puissances(app,app.murs, nmurs, nrayons, nrefl, TX1_1, n, Ym, Y, Xm, X, Z0, ...
    beta, omega, epsilon0, mu0, puissances_dbm, 0, facteurTX1, gain_max, delta, phi_3db, TXs, k);

TX1_2 = [99;-29];
TXs(:,3) = TX1_2;
k = 3;
[puissances_dbm]  = calcul_puissances(app,app.murs, nmurs, nrayons, nrefl, TX1_2, n, Ym, Y, Xm, X, Z0, ...
    beta, omega, epsilon0, mu0, puissances_dbm, 0, facteurTX1, gain_max, delta, phi_3db, TXs, k);

%verification puissance maximale
for i=1:45*n
      for j=10*n:n*110 + 1
           if puissances_dbm(i,j) > -30 
            disp('Erreur: exposition onde admise dépassée !')
          end
     end
end
for i=n*45+1:n*70+1
    for j=85*n:n*110 + 1
        if puissances_dbm(i,j) > -30 
            disp('Erreur: exposition onde admise dépassée !')
        end
    end
end
%calcul debit
for ind_y=1:n*(Y-Ym)+1
    for ind_x=1:n*(X-Xm)+1 
        debit(ind_y,ind_x) = 15*(puissances_dbm(ind_y,ind_x)+80)+50;
    end
end


% hold on;
% imagesc(app.UIAxes,[Xm,X], [Ym,Y], puissances_dbm, [min_dbm, max_dbm]); %dessine le graphe de la repartition de puissance
% xlabel('mètre'); ylabel('mètre');
% set(gca,'YDir','normal') %sinon inversion axe Y
% dessine_murs(app,murs); %dessine les murs

toc %mesure temps fin
       end


    function affiche_rayons(app)


      nmurs = numel(app.murs); %nombre de murs

      nrefl = 0; %nombre de reflexions
      nrayons = nombre_rayons(app,nmurs, nrefl);
      f = (26*10^9); % 5G 26GHz
      omega = (2*pi*f);
      epsilon0 = (8.85*10^-12);
      mu0 = (4*pi*10^-7);
      c = (3*10^8);
      Z0 = sqrt(mu0/epsilon0);
      beta = (omega/c);
      TX = [-10;0.5]; %fixé


      dessine_murs(app,app.murs); %dessine les murs
      hold(app.UIAxes,'on')
      for refl=1:nrefl %on boucle sur le nombre de reflexions
        comb_n = combinaisons_n(app,nmurs, nrayons(refl+1), refl); %combinaisons de reflexion
        for i=1:nrayons(refl+1) %trace tous les rayons et calcule les champs/coefficients
          TXp = images_nreflexions(app,app.murs, comb_n(i,:), TX, refl); %calcul images pour reflexions donnees
          [ptsI, rayon_poss] = trajectoire_rayons(app,app.murs, TXp, app.RX1, refl); %donne la trajectoire d'un rayon

          if rayon_poss == 1 %verifie si le rayon est possible
           
            coefficient = calcul_coefficient(app,app.murs, nmurs, app.RX1, ptsI, TX, refl, Z0, beta, omega, epsilon0, mu0);                   
            if ~isnan(coefficient) %on enlève les cas ou le rayon se reflete 2 fois sur un coin
                
                dessine_rayons(app,app.RX1,ptsI,TX,refl)
                hold(app.UIAxes,'on')
            end
          end
        end
      end
 
      dessine_rayons(app,app.RX1,app.RX1,TX,1)
      hold(app.UIAxes,'on')


       end

       function T0 = transmission(app,murs, nmurs, ptA, ptB, ind1, ind2, Z0, beta, omega, epsilon0, mu0) 
%calcul coefficient de transmission pour un segment entre 2 points
    T0 = 1;
    for i=1:nmurs
        if (i ~= ind1 && i ~= ind2) %on exclut les cas ou il y a reflexion
            mur = murs{i};
            eps_r = mur(1,3);
            conductivite = mur(2,3);
            k = (conductivite/omega);
            if eps_r == 4.6 %on regarde quelle est l'epaisseur du mur
                l = 0.3;
            elseif eps_r == 5
                l = 0.5;
            elseif eps_r == 2.25
                l = 0.1;
            end        
            epsilon = (eps_r*epsilon0);
            eps_im = epsilon - 1i*(conductivite/omega);
            gamma = 1i*omega*sqrt(mu0*eps_im);
            Zm = sqrt(mu0/(epsilon-1j*k));
            ptI = point_intersection(app,mur, ptA, ptB);
            inter = intersection(app,mur, ptI, ptA, ptB);
            if inter == 1 %cas ou il y a transmission
                [cosi, sini] = calcul_cosi(app,mur, ptA, ptB);
                sint = sqrt(1/eps_r)*sini; 
                cost = sqrt(1-sint^2);
                s = (l/cost);
                gamma_per = (Zm*cosi-Z0*cost)/(Zm*cosi+Z0*cost);
                T0p = ((1-gamma_per^2)*exp(-gamma*s))/...
                    (1-(gamma_per^2)*exp(-2*gamma*s)*exp(1j*beta*2*s*sint*sini));
                T0 = T0*T0p;
            end
        end
    end
       end

       function [ptsI, rayon_poss] = trajectoire_rayons(app,murs, TXp, RX, nrefl)
    ptsI = zeros(3, nrefl);
    for i=1:nrefl
        ind_mur = TXp(3,nrefl+1-i);
        mur = murs{ind_mur};
        if i == 1
            ptsI(:,i) = [point_intersection(app,mur, RX, TXp(1:2,nrefl)); ind_mur];
        else
            ptsI(:,i) = [point_intersection(app,mur, ptsI(1:2,i-1), TXp(1:2,nrefl+1-i)); ind_mur];
        end
    end
    rayon_poss = 1; %indique si la trajectoire du rayon est possible
    inter = intersection(app,murs{ptsI(3,1)}, ptsI(1:2,1), RX, TXp(1:2,nrefl));
    if inter == 0
        rayon_poss = 0;
    end
    for j=2:nrefl
        mur = murs{ptsI(3,j)};
        inter = intersection(app,mur, ptsI(1:2,j), ptsI(1:2,j-1), TXp(1:2,nrefl+1-j));
        if inter == 0
            rayon_poss = 0;
        end
    end
       end

       function gamma_mur = reflexion(app,mur, ptA, ptB, Z0, beta, omega, epsilon0, mu0) 
%calcul coefficient de reflexion
    [cosi, sini] = calcul_cosi(app,mur, ptA, ptB); 
    eps_r = mur(1,3);
    sint = (sqrt(1/eps_r)*sini);
    cost = sqrt(1-sint^2);
    conductivite = mur(2,3);
    k = (conductivite/omega);
    if eps_r == 4.6 %on regarde quelle est l'epaisseur du mur
        l = 0.3;
    elseif eps_r == 5
        l = 0.5;
    elseif eps_r == 2.25
        l = 0.1;
    end
    epsilon = (eps_r*epsilon0);
    eps_im = epsilon - 1i*(conductivite/omega);
    gamma = 1i*omega*sqrt(mu0*eps_im);
    Zm = sqrt(mu0/(epsilon-1j*k));
    s = (l/cost);
    gamma_per = (Zm*cosi-Z0*cost)/(Zm*cosi+Z0*cost); %perpendiculaire
    gamma_mur = gamma_per - (1-gamma_per^2)*(gamma_per*exp(-2*gamma*s)*exp(1j*beta*2*s*sint*sini))/...
        (1-(gamma_per^2)*exp(-2*gamma*s)*exp(1j*beta*2*s*sint*sini));
       end


       function ptI = point_intersection(~,mur, ptA, ptB) 
%calcul de l'intersection de 2 droites connaissant 2 points de chaque droite
    mx = mur(1,1)-mur(1,2); 
    my = mur(2,1)-mur(2,2);
    px = ptA(1)-ptB(1);
    py = ptA(2)-ptB(2);
    if mx == 0 %mur vertical
        xI = mur(1,1);
        yI = py/px*(xI-ptA(1))+ptA(2); %calcul de y connaissant x
    elseif px == 0 %rayon vertical
        xI = ptA(1);
        yI = my/mx*(xI-mur(1,1))+mur(2,1);
    else %autres cas
        myx = my/mx; %pentes des droites
        pyx = py/px;
        xI = (ptA(2)-pyx*ptA(1)-mur(2,1)+myx*mur(1,1))/(myx-pyx);
        yI = my/mx*(xI-mur(1,1))+mur(2,1);
    end
    ptI = [xI;yI];   
       end

       function normal = normale(~,mur) %fonction qui calcule la normale normalisée à un mur 
   mx = mur(1,1)-mur(1,2);
   my = mur(2,1)-mur(2,2);
   if my == 0
       normal = [0;1];
   else 
       ny = -mx/my;
       normal = [1;ny];
       normal = normal/sqrt(normal(1)^2+normal(2)^2);
    end
       end

       function nrayons = nombre_rayons(~,nmurs, nrefl)
%calcule le nombre de rayons pour un nombre max de réflexions
    nrayons = zeros(1,nrefl);
    nrayons(1) = 1; %un rayon pour aucune réflexion
    for i=1:nrefl %i est le nombre de réflexions prises en compte
        nrayref = nmurs; %nombre de rayons pour un nombre donné de réflexions
        for j=1:i-1
            nrayref = nrayref*(nmurs-1); 
        end
        nrayons(i+1) = nrayref;
    end
       end

       function inter = intersection(~,mur, ptI, ptA, ptB) %détermine si le rayon et le mur s'intersectent
    xI = ptI(1);
    yI = ptI(2);
    xmmin = min(mur(1,1),mur(1,2)); %points extremaux du mur et du rayon 
    xmmax = max(mur(1,1),mur(1,2));
    ymmin = min(mur(2,1),mur(2,2));
    ymmax = max(mur(2,1),mur(2,2));
    xpmin = min(ptA(1),ptB(1));
    xpmax = max(ptA(1),ptB(1));
    ypmin = min(ptA(2),ptB(2));
    ypmax = max(ptA(2),ptB(2));
    if (xmmin<=xI && xI<=xmmax && ymmin<=yI && yI<=ymmax) 
        %on vérifie si le point d'intersection se trouve bien sur le mur
        if  (xpmin<=xI && xI<=xpmax && ypmin<=yI && yI<=ypmax) %et sur le rayon
            inter = 1;
        else
            inter = 0;
        end
    else
        inter = 0;
    end
       end

       function TXp = images_nreflexions(app,murs, comb_n, TX, nrefl) 
%calcule les images de TX pour n reflexions
    TXp = zeros(3, nrefl);
    for i=1:nrefl
        ind_mur = comb_n(1,i);
        mur = murs{ind_mur};
        if i == 1
            TXp(:,i) = [image_TX(app,TX, mur); ind_mur];     
        else
            TXp(:,i) = [image_TX(app,TXp(1:2,i-1), mur); ind_mur];
        end
    end
       end


       function TXp = image_TX(app,TX, mur) %calcule l'image d'un point TX par rapport à un mur
    normal = normale(app,mur);
    ptI = point_intersection(app,mur, TX, TX+normal);
    TXp = TX - 2*(TX-ptI);
       end

       function dessine_rayons(app,RX, ptI, TX, nrefl) %fonction qui dessine les rayons
    for i=1:nrefl+1      
        if i == 1
            line(app.UIAxes,[RX(1),ptI(1,1)],[RX(2),ptI(2,1)])
        elseif i == nrefl+1
            line(app.UIAxes,[ptI(1,i-1),TX(1)],[ptI(2,i-1),TX(2)])
        else
            line(app.UIAxes,[ptI(1,i-1),ptI(1,i)],[ptI(2,i-1),ptI(2,i)])
        end
    end 
       end

       function dessine_murs(app,murs) %fonction qui dessine les murs à partir de leur liste
    nmurs = numel(murs);
    for i=1:nmurs
        line(app.UIAxes,murs{i}(1,1:2), murs{i}(2,1:2),'Color', 'black')
    end
       end

       function [comb, ind] = combinaisons_rec(app,comb, nmurs, nrefl, i, ind, vec) 
%fonction qui calcule les combinaisons avec recursivite
    for j=1:nmurs
        if j ~= i
            if nrefl == 1
                vec(1,nrefl) = j;
                comb(ind,:) = vec;
                ind = ind+1;
            else
                vec(1,nrefl) = j;
                k = j;
                [comb, ind] = combinaisons_rec(app,comb, nmurs, nrefl-1, k, ind, vec);
            end
        end
    end
       end

       function [comb, ind] = combinaisons_n(app,nmurs, nrayons, nrefl) 
%fonction qui calcule les combinaisons de n réflexions
    ind = 1;
    i = 0;
    comb = zeros(nrayons, nrefl);
    vec = zeros(1,nrefl); %pour stocker les valeurs
    [comb, ind] = combinaisons_rec(app,comb, nmurs, nrefl, i, ind, vec);
       end

       function [puissances_dbm] = calcul_puissances(app,murs, nmurs, nrayons, nrefl, TX, n, Ym, Y, Xm, X, Z0, ...
    beta, omega, epsilon0, mu0, puissances_dbm, type, facteurTX, gain_max, delta, phi_3db, TXs, k)
  ind_y = 1;
  for y=n*Ym:n*Y
    ind_x = 1;
    for x=n*Xm:n*X
      RX = [(x/n);(y/n)];
      Prx = 0; %initialisation puissance   

      %cas direct
      dist_tot = sqrt((TX(1)-RX(1))^2+(TX(2)-RX(2))^2); %distance parcourue par le rayon
      coefficient = transmission(app,murs, nmurs, TX, RX, 0, 0, Z0, beta, omega, epsilon0, mu0);
      E = coefficient*(exp(-1j*(beta)*dist_tot))/dist_tot;
      Prx = Prx + abs(E)^2;        
      %cas avec reflexions

      for refl=1:nrefl %on boucle sur le nombre de reflexions
        comb_n = combinaisons_n(app,nmurs, nrayons(refl+1), refl); %combinaisons de reflexion
        for i=1:nrayons(refl+1) %trace tous les rayons et calcule les champs/coefficients
          TXp = images_nreflexions(app,murs, comb_n(i,:), TX, refl); %calcul images pour reflexions donnees
          [ptsI, rayon_poss] = trajectoire_rayons(app,murs, TXp, RX, refl); %donne la trajectoire d'un rayon

          if rayon_poss == 1 %verifie si le rayon est possible
            TXn = TXp(1:2,refl); %derniere image de TX 
            dist_tot = sqrt((TXn(1)-RX(1))^2+(TXn(2)-RX(2))^2); %distance parcourue par le rayon
            coefficient = calcul_coefficient(app,murs, nmurs, RX, ptsI, TX, refl, Z0, beta, omega, epsilon0, mu0);                   
            if ~isnan(coefficient) %on enlève les cas ou le rayon se reflete 2 fois sur un coin
              E = coefficient*(exp(-1j*(beta)*dist_tot))/dist_tot;
              if type == 1 %antenne de type TX3
                vect = TX-ptsI(1:2,refl); %premier segment 
                cos_phi = abs([1,0]*vect/sqrt(vect(1)^2+vect(2)^2));
                phi = acos(cos_phi);
                gain_theta = gain_max - 12*((phi-delta)/(phi_3db))^2; %en db
                gainTX3 = 10^(gain_theta/10);
                Prx = Prx + gainTX3*abs(E)^2; 
              else %antennes type TX1 et TX2
                Prx = Prx + abs(E)^2;            
              end
            end
          end
        end
      end 
      
      Prx = 30 + 10*log10(Prx*facteurTX); %dbm = 30+10*log(Prx);
      for u=1:k
        TXx = TXs(1,u);
        TXy = TXs(2,u);
        cercle_exclusion = ((x/n)-TXx)^2 + ((y/n)-TXy)^2;
        if cercle_exclusion <= (0.5)^2
          Prx = -inf;
        end
      end      
      if puissances_dbm(ind_y,ind_x) == 0 || Prx == -inf %matrice vide
        puissances_dbm(ind_y,ind_x) = Prx;
      else
        puissances_dbm(ind_y,ind_x) = max(puissances_dbm(ind_y,ind_x), Prx); 
      end 
      ind_x = ind_x + 1;       
    end
    ind_y = ind_y + 1;
  end
       end

       function [cosi, sini] = calcul_cosi(app,mur, ptA, ptB) 
%fonction calculant le cosinus du rayon incident
    normal = normale(app,mur);
    d = ptA-ptB;
    cosi = abs(d'*normal/sqrt(d(1)^2+d(2)^2));
    sini = sqrt(1-cosi^2);
       end

       function coefficient = calcul_coefficient(app,murs, nmurs, ...
    RX, ptsI, TX, nrefl, Z0, beta, omega, epsilon0, mu0) 
    coefficient = 1;

    %cas derniere reflexion
    mur = murs{ptsI(3,1)};
    gamma_mur = reflexion(app,mur, RX, ptsI(1:2,1), Z0, beta, omega, epsilon0, mu0);
    coefficient = coefficient*gamma_mur;
   
    %cas dernier segment et premier segment du rayon
    T0 = transmission(app,murs, nmurs, RX, ptsI(1:2,1), ptsI(3,1), ...
        ptsI(3,1), Z0, beta, omega, epsilon0, mu0);
    coefficient = coefficient*T0;
    T0 = transmission(app,murs, nmurs, ptsI(1:2,nrefl), TX, ...
        ptsI(3,nrefl), ptsI(3,nrefl), Z0, beta, omega, epsilon0, mu0);
    coefficient = coefficient*T0;
    
    for i=2:nrefl %cas segments intermediaires
        mur = murs{ptsI(3,i)};
        gamma_mur = reflexion(app,mur, ptsI(1:2,i-1), ptsI(1:2,i), Z0, beta, omega, epsilon0, mu0);
        coefficient = coefficient*gamma_mur; 
        T0 = transmission(app,murs, nmurs, ptsI(1:2,i-1), ptsI(1:2,i), ...
            ptsI(3,i-1), ptsI(3,i), Z0, beta, omega, epsilon0, mu0);
        coefficient = coefficient*T0;
    end
       end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AfficherendbitButton_2
        function AfficherendbitButton_2Pushed(app, event)
            
          [debit,~,X,Xm,Y,Ym]= main(app);

extremums = [100, 350]; %minimum = débit minimal que les robots puissent fonctionner correctement.
hold(app.UIAxes,'on');
imagesc(app.UIAxes,[Xm,X], [Ym,Y], debit, extremums); %dessine le graphe de la repartition de débit
xlabel(app.UIAxes,'mètre'); ylabel(app.UIAxes,'mètre');title(app.UIAxes,'Répartition de débit en MB/s');colorbar(app.UIAxes)
dessine_murs(app,app.murs); %dessine les murs
hold(app.UIAxes,'on');
        end

        % Button pushed function: AfficherendBmButton
        function AfficherendBmButtonPushed(app, event)
           
            [~,puissances_dbm,X,Xm,Y,Ym]= main(app);
         
min_dbm = round(min(puissances_dbm(:)));  
max_dbm = round(max(puissances_dbm(:)));
hold(app.UIAxes,'on');
imagesc(app.UIAxes,[Xm,X], [Ym,Y], puissances_dbm, [min_dbm, max_dbm]); %dessine le graphe de la repartition de débit
xlabel(app.UIAxes,'mètre'); ylabel(app.UIAxes,'mètre');title(app.UIAxes,'Répartition de puissance en dBm');colorbar(app.UIAxes)
dessine_murs(app,app.murs); %dessine les murs
hold(app.UIAxes,'on');
        end

        % Button pushed function: AffichelesrayonsButton
        function AffichelesrayonsButtonPushed(app, event)
            
            affiche_rayons(app);
         
        end

        % Value changed function: RXenxEditField
        function RXenxEditFieldValueChanged(app, event)
            app.RX1(1) = event.Value;
            
        end

        % Value changed function: RXenyEditField
        function RXenyEditFieldValueChanged(app, event)
            app.RX1(2) = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            dessine_murs(app,app.murs);
        end

        % Callback function
        function CleanButtonPushed(app, event)
            delete(affiche_rayons(app));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'winter');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            colormap(app.UIAxes, 'winter')
            app.AfficherendbitButton_2 = uibutton(app.UIFigure, 'push');
            app.AfficherendbitButton_2.ButtonPushedFcn = createCallbackFcn(app, @AfficherendbitButton_2Pushed, true);
            app.AfficherendbitButton_2.Text = 'Afficher en débit ';
            app.AfficherendBmButton = uibutton(app.UIFigure, 'push');
            app.AfficherendBmButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherendBmButtonPushed, true);
            app.AfficherendBmButton.Text = 'Afficher en dBm';
            app.AffichelesrayonsButton = uibutton(app.UIFigure, 'push');
            app.AffichelesrayonsButton.ButtonPushedFcn = createCallbackFcn(app, @AffichelesrayonsButtonPushed, true);
            app.AffichelesrayonsButton.Text = 'Affiche les rayons';
            app.RXenxEditFieldLabel = uilabel(app.UIFigure);
            app.RXenxEditFieldLabel.Text = 'RX en x ';
            app.RXenxEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXenxEditField.ValueChangedFcn = createCallbackFcn(app, @RXenxEditFieldValueChanged, true);
            app.RXenyEditFieldLabel = uilabel(app.UIFigure);
            app.RXenyEditFieldLabel.Text = 'RX en y ';
            app.RXenyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXenyEditField.ValueChangedFcn = createCallbackFcn(app, @RXenyEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end